classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        plotButton       matlab.ui.control.Button
        Label            matlab.ui.control.Label
        Time             matlab.ui.control.NumericEditField
        Label_4          matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        loadBurton       matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        step             matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        Target           matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        N %Время
        x1 = []
        x2 = []
        labely
        y
        stations = []
        Tabledate
        diff_date
    end
    

    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.N = get(app.Time,'Value');
            h = get(app.step, 'Value');
            k = get(app.Target, 'Value');
            app.UIAxes.YLabel.String = app.labely;
            data = plot_system_full(app.diff_date(3), h, app.x1, app.x2, k, app.diff_date);
            plot(app.UIAxes, 0:h:app.diff_date(3), data, ['r', 'g', 'b']);
            app.UIAxes.XLim = [0 app.diff_date(3)];
        end

        % Button pushed function: loadBurton
        function loadBurtonPushed(app, event)
            FILE = uigetfile("*.xlsx");
            app.y = get(app.DropDown, 'Value');
            app.labely = get(app.DropDown, 'Items');
            app.Tabledate = readtable('data.xlsx','Range','C3:C5','ReadVariableNames',false);
            app.diff_date = hours(diff(app.Tabledate.Var1)./24);
            app.diff_date = [0 app.diff_date(1) app.diff_date(1) + app.diff_date(2)];
            app.Time.Value = app.diff_date(3);
            if app.y == 1
                app.x1 = xlsread(FILE, "E3:E5");
                app.x2 = xlsread(FILE, "G3:G5");
                app.x1 = app.x1 * 1000;
            else  
                app.x1 = xlsread(FILE, "D3:D5");
                app.x2 = xlsread(FILE, "F3:F5");
                app.x1 = app.x1 / 100000;
                app.x2 = app.x2 / 1000;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Модель "хищник-жертва с питанием"')
            xlabel(app.UIAxes, 'Время, дни')
            ylabel(app.UIAxes, 'Y')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'Построить график';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Время';
            app.Time = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Формат исходных данных';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Биомасса (мг/л)', 'Численность (экз/м3)'};
            app.DropDown.ItemsData = {'1', '2'};
            app.DropDown.Value = '1';
            app.loadBurton = uibutton(app.UIFigure, 'push');
            app.loadBurton.ButtonPushedFcn = createCallbackFcn(app, @loadBurtonPushed, true);
            app.loadBurton.Text = 'Загрузить данные';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Шаг';
            app.step = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Увеличение';
            app.Target = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end